classdef Leap_Year_Calculator < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        LastYear                 matlab.ui.control.NumericEditField
        Image2                   matlab.ui.control.Image
        LastYearEditFieldLabel   matlab.ui.control.Label
        Result                   matlab.ui.control.TextArea
        ResultTextAreaLabel      matlab.ui.control.Label
        Check                    matlab.ui.control.Button
        FirstYear                matlab.ui.control.NumericEditField
        FirstYearEditFieldLabel  matlab.ui.control.Label
        Mode                     matlab.ui.control.DropDown
        ModeDropDownLabel        matlab.ui.control.Label
        LeapYearCalculator       matlab.ui.control.Label
        Image                    matlab.ui.control.Image
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: Mode
        function ModeValueChanged(app, event)
            mode = app.Mode.Value;
            if strcmp(mode, 'Range Year')
                app.LastYear.Visible = 'on';
                app.LastYear.Visible = 'on';
            else
                app.LastYear.Visible = 'off';
                app.LastYear.Visible = 'off';
            end
        end

        % Fungsi bantu: cek tahun kabisat
        function tf = isLeapYear(app, year)
            if mod(year,400) == 0
                tf = true;
            elseif mod(year,100) == 0
                tf = false;
            elseif mod(year,4) == 0
                tf = true;
            else
                tf = false;
            end
        end

        % Button pushed function: Check
        function CheckPushed(app, event)
            mode = app.Mode.Value;
            y1 = app.FirstYear.Value;
            y2 = app.LastYear.Value;
            switch mode
                
                case 'Year'
                    if app.isLeapYear(y1)
                        app.Result.Value = sprintf('%d is a leap year.', y1);
                    else
                        app.Result.Value = sprintf('%d NOT a leap year.', y1);
                    end
                
                case 'Range Year'
                    leapYears = [];
                    for y = y1:y2
                        if app.isLeapYear(y)
                            leapYears(end+1) = y; %#ok<AGROW>
                        end
                    end
                    if isempty(leapYears)
                        app.Result.Value = sprintf('There are no leap years between %d - %d.', y1, y2);
                    else
                        app.Result.Value = sprintf('Leap year between %d - %d:\n%s', ...
                            y1, y2, num2str(leapYears));
                    end
                
                case 'Next Leap Year'
                    nextYear = y1 + 1
                    while ~app.isLeapYear(nextYear)
                        nextYear = nextYear + 1;
                    end
                    app.Result.Value = sprintf('The leap year after %d is %d.', y1, nextYear);
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'Putih Pink Modern Undangan Ulang Tahun (1).png');
            app.LeapYearCalculator = uilabel(app.UIFigure);
            app.LeapYearCalculator.Text = 'Leap Year Calculator';
            app.ModeDropDownLabel = uilabel(app.UIFigure);
            app.ModeDropDownLabel.Text = 'Mode';
            app.Mode = uidropdown(app.UIFigure);
            app.Mode.Items = {'Year', 'Range Year', 'Next Leap Year'};
            app.Mode.ValueChangedFcn = createCallbackFcn(app, @ModeValueChanged, true);
            app.Mode.Value = 'Year';
            app.FirstYearEditFieldLabel = uilabel(app.UIFigure);
            app.FirstYearEditFieldLabel.Text = 'First Year';
            app.FirstYear = uieditfield(app.UIFigure, 'numeric');
            app.Check = uibutton(app.UIFigure, 'push');
            app.Check.ButtonPushedFcn = createCallbackFcn(app, @CheckPushed, true);
            app.Check.Text = 'Check';
            app.ResultTextAreaLabel = uilabel(app.UIFigure);
            app.ResultTextAreaLabel.Text = 'Result';
            app.Result = uitextarea(app.UIFigure);
            app.LastYearEditFieldLabel = uilabel(app.UIFigure);
            app.LastYearEditFieldLabel.Text = 'Last Year';
            app.Image2 = uiimage(app.UIFigure);
            app.Image2.ImageSource = fullfile(pathToMLAPP, 'Putih Pink Modern Undangan Ulang Tahun (2).png');
            app.LastYear = uieditfield(app.UIFigure, 'numeric');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Leap_Year_Calculator

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end